classdef V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        maeValTRAIN                 matlab.ui.control.Label
        maeValTEST                  matlab.ui.control.Label
        predictingFeatureSelection  matlab.ui.control.ListBox
        predictingFeatureSelectionLabel  matlab.ui.control.Label
        maxSplittingSlider          matlab.ui.control.Slider
        MAXSPLITTINGSliderLabel     matlab.ui.control.Label
        featureSelection            matlab.ui.container.CheckBoxTree
        featureSelectionLabel       matlab.ui.control.Label
        saveSubmissionButton        matlab.ui.control.Button
        startButton                 matlab.ui.control.Button
        testButton                  matlab.ui.control.Button
        trainButton                 matlab.ui.control.Button
        graph                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TRAIN_DATA %TRAIN RAW DATA
        TEST_DATA %TEST RAW DATA
        X %TRAIN_DATA filtered by features
        y %TRAIN_DATA filtered by predicting value 
        TEST_X %TEST_DATA filtered by features
        TEST_Y %TEST_DATA filtered by predicting value 
        PREDICTING_VALUE %STRING format predicting value
        SLIDE_INTERVAL %Slide interval array on gui
        MAE_TRAIN %MAE calculated on TRAIN
        MAE_TEST %MAE calculated on TEST
        CHANGING_VALUE %Live data at slide on gui
    end
    
    methods (Access = private)
        
        function SUBMISSION_KAGGLE_FORMAT(app, predictions, id, variableNames, path) %SUBMISSON FORMAT KAGGLE
            submission = table(id, predictions);
            submission.Properties.VariableNames = variableNames;
            writetable(submission, path);
        end

        function mae = CAL_MALE(app, orgSig,recSig,varargin) %MAE CALCULATION FUNCTION
            if isempty(varargin)
                boun = 0;
            else boun = varargin{1};
            end
            if size(orgSig,2)==1      
                orgSig = orgSig(boun+1:end-boun,:);
                recSig = recSig(boun+1:end-boun,:);
            else                     
                orgSig = orgSig(boun+1:end-boun,boun+1:end-boun,:);
                recSig = recSig(boun+1:end-boun,boun+1:end-boun,:);
            end
            
            absErr = norm(orgSig(:)-recSig(:),1);
            mae = absErr/length(orgSig(:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: trainButton
        function trainButtonPushed(app, event)
            [name, path] = uigetfile("*.csv"); %Train data selecting
            app.TRAIN_DATA = fullfile(path, name); 
            if ~isequal(app.TRAIN_DATA, 0) %If data is selected than, appear other related parts.
                set(app.testButton, "visible", "on");
                set(app.featureSelection, "visible", "on");
                set(app.predictingFeatureSelection, "visible", "on");
                set(app.featureSelectionLabel, "visible", "on");
                set(app.predictingFeatureSelectionLabel, "visible", "on");
            end
            app.TRAIN_DATA = readtable(app.TRAIN_DATA);
            columnNamesTRAIN = app.TRAIN_DATA.Properties.VariableNames; %To edit selection feature and predicting, export columnNames
            for i=1:length(columnNamesTRAIN)
                child = uitreenode(app.featureSelection,"Text", columnNamesTRAIN{1,i});
            end
            app.predictingFeatureSelection.Items = columnNamesTRAIN;
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            [name, path] = uigetfile("*.csv");  %Test data selecting
            app.TEST_DATA = fullfile(path, name);
            if ~isequal(app.TEST_DATA, 0) %If data is selected than, appear other related parts.
                set(app.saveSubmissionButton, "visible", "on");
                set(app.startButton, "visible", "on");
                set(app.MAXSPLITTINGSliderLabel, "visible", "on");
            end 
            app.TEST_DATA = readtable(app.TEST_DATA);
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            featureTree = app.featureSelection.CheckedNodes; %To get selected features on Tree Check Box
            app.PREDICTING_VALUE = app.predictingFeatureSelection.Value; %To get selected predicting feature on List Box
            features = [""];
            for i=1:length(featureTree)
                feature = convertCharsToStrings(featureTree(i).Text);
                features(end + 1) = feature;
            end
            features(1) = [] ;
            app.X = app.TRAIN_DATA(:, features); %Filtering on raw data TRAIN
            app.y = app.TRAIN_DATA(:, app.PREDICTING_VALUE); 

            app.TEST_X = app.TEST_DATA(:, features);
            
            app.MAE_TEST = [];
            app.MAE_TRAIN = [];
            trueValuesTEST = readtable("/home/muhammed/Desktop/maxSplitSimulator/trueValues.csv"); %WARNING!!! That was a competition so, true values are shared later than test data. YOU HAVE TO FIX THIS OBJECT ACCORDING TO TEST DATA RESULTS
            %trueValuesTEST = readtable("D:/maxSplitSimulatorGUI/trueValues.csv");
            trueValuesTEST = trueValuesTEST(:, app.PREDICTING_VALUE);
            trueValuesTEST = table2array(trueValuesTEST);
            app.SLIDE_INTERVAL = (app.maxSplittingSlider.Limits(1): app.maxSplittingSlider.Limits(2)); %To edit graphs horizantal
            
            for i=1:length(app.SLIDE_INTERVAL) %find mae for train and test for each number on slide, than add to their arrays', to plot graph
                TreeRegressor = fitctree(app.X, app.y, "MaxNumSplits", i);
                predictedCurrentTEST = TreeRegressor.predict(app.TEST_X);
                predictedCurrentTRAIN = TreeRegressor.predict(app.X);
                maeCurrentTEST = app.CAL_MALE(predictedCurrentTEST, trueValuesTEST);
                maeCurrentTRAIN = app.CAL_MALE(predictedCurrentTRAIN, table2array(app.y));
                app.MAE_TEST(end + 1) = maeCurrentTEST;
                app.MAE_TRAIN(end + 1) = maeCurrentTRAIN;
            end

            set(app.maxSplittingSlider, "visible", "on"); %Arranging slider visiblity
            set(app.graph, "visible", "on");
            plot(app.graph, app.SLIDE_INTERVAL, app.MAE_TEST);
            hold(app.graph,'on');
            plot(app.graph, app.SLIDE_INTERVAL, app.MAE_TRAIN);
            hold(app.graph,'on');
            app.graph.Title.String = 'ERROR OF TRAIN AND TEST';
            app.graph.XLabel.String = "SPLIT NUMBER";
            app.graph.YLabel.String = "ERROR";
        end

        % Button pushed function: saveSubmissionButton
        function saveSubmissionButtonPushed(app, event)
            path = "/home/muhammed/Desktop/submission.csv"; %WARNING!!! YOU HAVE TO CHANGE THIS OBJECT ACCORDING TO YOUR PATH
            predicted = fitctree(app.X, app.y, "MaxNumSplits", app.CHANGING_VALUE);
            predicted = predicted.predict(app.TEST_X);
            app.SUBMISSION_KAGGLE_FORMAT(predicted, app.TEST_DATA.Id, ["Id", app.PREDICTING_VALUE], path); %WARNING!!! YOU MAY WANT TO CHANGE THIS OBJECT ACCORDING TO KAGGLE FORMAT OR SOMETHING!!! (The usage of function shown above)
        end

        % Value changing function: maxSplittingSlider
        function maxSplittingSliderValueChanging(app, event)
            app.CHANGING_VALUE = event.Value; %Get live value on slider
            app.CHANGING_VALUE = round(app.CHANGING_VALUE); %Value was float
            set(app.maeValTRAIN, "Text", string([app.CHANGING_VALUE, app.MAE_TRAIN(app.CHANGING_VALUE)]), "FontColor", [1 0 0]); %Values of TRAIN
            set(app.maeValTEST, "Text", string([app.CHANGING_VALUE, app.MAE_TEST(app.CHANGING_VALUE)]), "FontColor", [0 0 1]); %Values of TEST
            
            %GRAPH EDITTING
            app.graph.Title.String = 'ERROR OF TRAIN AND TEST';
            app.graph.XLabel.String = "SPLIT NUMBER";
            legend(app.graph, "maeTEST", "maeTRAIN", "valueTEST", "valueTRAIN");
            app.graph.YLabel.String = "ERROR";
            plot(app.graph, app.SLIDE_INTERVAL, app.MAE_TEST);
            hold(app.graph,'on');
            plot(app.graph, app.SLIDE_INTERVAL, app.MAE_TRAIN);
            hold(app.graph,'on');
            plot(app.graph, app.CHANGING_VALUE, app.MAE_TEST(app.CHANGING_VALUE), "s" ,"color", [1 0 0]);
            hold(app.graph, "on")
            plot(app.graph, app.CHANGING_VALUE, app.MAE_TRAIN(app.CHANGING_VALUE), "*" ,"color", [0 0 1]);
            hold(app.graph, "off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Title')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            zlabel(app.graph, 'Z')
            app.graph.Visible = 'off';
            app.trainButton = uibutton(app.UIFigure, 'push');
            app.trainButton.ButtonPushedFcn = createCallbackFcn(app, @trainButtonPushed, true);
            app.trainButton.Text = 'TRAIN';
            app.testButton = uibutton(app.UIFigure, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Visible = 'off';
            app.testButton.Text = 'TEST';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Visible = 'off';
            app.startButton.Text = 'START';
            app.saveSubmissionButton = uibutton(app.UIFigure, 'push');
            app.saveSubmissionButton.ButtonPushedFcn = createCallbackFcn(app, @saveSubmissionButtonPushed, true);
            app.saveSubmissionButton.Visible = 'off';
            app.saveSubmissionButton.Text = 'SAVE SUBMISSION';
            app.featureSelectionLabel = uilabel(app.UIFigure);
            app.featureSelectionLabel.Visible = 'off';
            app.featureSelectionLabel.Text = 'Feature Selection';
            app.featureSelection = uitree(app.UIFigure, 'checkbox');
            app.featureSelection.Visible = 'off';
            app.MAXSPLITTINGSliderLabel = uilabel(app.UIFigure);
            app.MAXSPLITTINGSliderLabel.Visible = 'off';
            app.MAXSPLITTINGSliderLabel.Text = 'MAX SPLITTING';
            app.maxSplittingSlider = uislider(app.UIFigure);
            app.maxSplittingSlider.Limits = [5 500];
            app.maxSplittingSlider.ValueChangingFcn = createCallbackFcn(app, @maxSplittingSliderValueChanging, true);
            app.maxSplittingSlider.Visible = 'off';
            app.maxSplittingSlider.Value = 5;
            app.predictingFeatureSelectionLabel = uilabel(app.UIFigure);
            app.predictingFeatureSelectionLabel.Visible = 'off';
            app.predictingFeatureSelectionLabel.Text = 'Predicting Selection';
            app.predictingFeatureSelection = uilistbox(app.UIFigure);
            app.predictingFeatureSelection.Items = {};
            app.predictingFeatureSelection.Visible = 'off';
            app.predictingFeatureSelection.Value = {};
            app.maeValTEST = uilabel(app.UIFigure);
            app.maeValTEST.Text = '';
            app.maeValTRAIN = uilabel(app.UIFigure);
            app.maeValTRAIN.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end